classdef chooseGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        ColoringMarkDesiredColoringSizesLabel  matlab.ui.control.Label
        OKButton  matlab.ui.control.Button
    end


    properties (Access = private)
        myconfig % Description
        nAgents % Description
        checkCell
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, config)
           app.myconfig = config;
           app.nAgents = config.Environment.number_of_agents;
           app.checkCell = cell(1,app.nAgents+1);
           t = app.ColoringMarkDesiredColoringSizesLabel.Position(2)-10;
           for i = 1:app.nAgents+1
               app.checkCell{i} = uicheckbox(app.UIFigure);
               t = t-25;
               app.checkCell{i}.Position = [34 t 71 20];
               app.checkCell{i}.Text = [num2str(i-1),'-Color'];   
           end
           t = t - 40;

        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            color = zeros(1,app.nAgents+1);
            for i = 1:app.nAgents+1
                if app.checkCell{i}.Value
                    color(i) = 1;
                end
            end
            exh2(app.myconfig,color);
            closereq;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.ColoringMarkDesiredColoringSizesLabel = uilabel(app.UIFigure);
            app.ColoringMarkDesiredColoringSizesLabel.Text = 'Coloring - Mark Desired Coloring Sizes';
            app.OKButton = uibutton(app.UIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
        end
    end

    methods (Access = public)

        % Construct app
        function app = chooseGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end