classdef exGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                  matlab.ui.Figure
        modelLabel                    matlab.ui.control.Label
        EnvironmentLabel              matlab.ui.control.Label
        NumberOfAgentsEditFieldLabel  matlab.ui.control.Label
        NumberOfAgents                matlab.ui.control.NumericEditField
        OKButton                      matlab.ui.control.Button
        PartnershipCostLabel          matlab.ui.control.Label
        MembershipCost                matlab.ui.control.NumericEditField
        DeltaLabel                    matlab.ui.control.Label
        Delta                         matlab.ui.control.NumericEditField
        UITable                       matlab.ui.control.Table
        InteragentValueLabel          matlab.ui.control.Label
        all                           matlab.ui.control.CheckBox
    end


    properties (Access = public)
        myConfig = demoConfig();     % Description
    end

    properties (Access = private)
    end

    methods (Access = private)
            
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.UITable.Data = zeros(1,3);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.myConfig.Environment.number_of_agents = app.NumberOfAgents.Value;
            app.myConfig.Model.club_membership_cost = app.MembershipCost.Value;
            app.myConfig.Model.delta = app.Delta.Value;
            app.myConfig.Model.w = app.UITable.Data;
            if app.all.Value
                exhaustive(app.myConfig);
            else
                chooseGui(app.myConfig);
            end
            closereq;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure;
            app.MainUIFigure.Name = 'Main';
            app.modelLabel = uilabel(app.MainUIFigure);
            app.modelLabel.Text = 'model';
            app.EnvironmentLabel = uilabel(app.MainUIFigure);
            app.EnvironmentLabel.Text = 'Environment';
            app.NumberOfAgentsEditFieldLabel = uilabel(app.MainUIFigure);
            app.NumberOfAgentsEditFieldLabel.Text = 'Number Of Agents';
            app.NumberOfAgents = uieditfield(app.MainUIFigure, 'numeric');
            app.NumberOfAgents.Limits = [3 9];
            app.NumberOfAgents.Value = 6;
            app.OKButton = uibutton(app.MainUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.PartnershipCostLabel = uilabel(app.MainUIFigure);
            app.PartnershipCostLabel.Text = 'Partnership Cost';
            app.MembershipCost = uieditfield(app.MainUIFigure, 'numeric');
            app.MembershipCost.Limits = [0 Inf];
            app.DeltaLabel = uilabel(app.MainUIFigure);
            app.DeltaLabel.Text = 'Delta';
            app.Delta = uieditfield(app.MainUIFigure, 'numeric');
            app.Delta.Limits = [0 1];
            app.UITable = uitable(app.MainUIFigure);
            app.UITable.ColumnName = {'w^h_h'; 'w^h_l'; 'w^l_l'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.InteragentValueLabel = uilabel(app.MainUIFigure);
            app.InteragentValueLabel.Text = 'Inter-agent Value';
            app.all = uicheckbox(app.MainUIFigure);
            app.all.Text = 'All Coloring';
            app.all.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = exGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end