classdef genGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MainUIFigure                  matlab.ui.Figure
        modelLabel                    matlab.ui.control.Label
        EnvironmentLabel              matlab.ui.control.Label
        NumberOfAgentsEditFieldLabel  matlab.ui.control.Label
        NumberOfAgents                matlab.ui.control.NumericEditField
        OKButton                      matlab.ui.control.Button
        MembershipCostEditFieldLabel  matlab.ui.control.Label
        MembershipCost                matlab.ui.control.NumericEditField
        DeltaLabel                    matlab.ui.control.Label
        Delta                         matlab.ui.control.NumericEditField
        UITable                       matlab.ui.control.Table
        IntrinsicValueLabel           matlab.ui.control.Label
        ProcessingLabel               matlab.ui.control.Label
        NumberOfGenerationsLabel      matlab.ui.control.Label
        generations                   matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        myConfig = demoConfig();     % Description
    end

    properties (Access = private)
    end

    methods (Access = private)
            
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
           app.UITable.Data = zeros(1,3);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.myConfig.Environment.number_of_agents = app.NumberOfAgents.Value;
            app.myConfig.Model.club_membership_cost = app.MembershipCost.Value;
            app.myConfig.Model.delta = app.Delta.Value;
            app.myConfig.Model.w = app.UITable.Data;
            app.myConfig.Processing.max_number_of_generations = app.generations.Value;
            
            colorGui(app.myConfig)
            closereq;
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MainUIFigure = uifigure;
            app.MainUIFigure.Name = 'Main';
            app.modelLabel = uilabel(app.MainUIFigure);
            app.modelLabel.Text = 'model';
            app.EnvironmentLabel = uilabel(app.MainUIFigure);
            app.EnvironmentLabel.Text = 'Environment';
            app.NumberOfAgentsEditFieldLabel = uilabel(app.MainUIFigure);
            app.NumberOfAgentsEditFieldLabel.Text = 'Number Of Agents';
            app.NumberOfAgents = uieditfield(app.MainUIFigure, 'numeric');
            app.NumberOfAgents.Limits = [3 18];
            app.NumberOfAgents.Value = 6;
            app.OKButton = uibutton(app.MainUIFigure, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.MembershipCostEditFieldLabel = uilabel(app.MainUIFigure);
            app.MembershipCostEditFieldLabel.Text = 'Membership Cost';
            app.MembershipCost = uieditfield(app.MainUIFigure, 'numeric');
            app.MembershipCost.Limits = [0 Inf];
            app.DeltaLabel = uilabel(app.MainUIFigure);
            app.DeltaLabel.Text = 'Delta';
            app.Delta = uieditfield(app.MainUIFigure, 'numeric');
            app.Delta.Limits = [0 1];
            app.UITable = uitable(app.MainUIFigure);
            app.UITable.ColumnName = {'w_1 '; 'w_2'; 'w_3'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.IntrinsicValueLabel = uilabel(app.MainUIFigure);
            app.IntrinsicValueLabel.Text = 'Intrinsic Value';
            app.ProcessingLabel = uilabel(app.MainUIFigure);
            app.ProcessingLabel.Text = 'Processing';
            app.NumberOfGenerationsLabel = uilabel(app.MainUIFigure);
            app.NumberOfGenerationsLabel.Text = 'Number Of Generations';
            app.generations = uieditfield(app.MainUIFigure, 'numeric');
            app.generations.Limits = [1 Inf];
            app.generations.Value = 50;
        end
    end

    methods (Access = public)

        % Construct app
        function app = genGui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MainUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MainUIFigure)
        end
    end
end