classdef mapGui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        MapFigure            matlab.ui.Figure
        UITable              matlab.ui.control.Table
        OK                   matlab.ui.control.Button
        LoadMatrixFileLabel  matlab.ui.control.Label
        LoadFile             matlab.ui.control.Button
    end


    properties (Access = private)
        myconfig
        nAgents % Description
        checkCell % Description
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, config)
           app.myconfig = config;
           app.nAgents = config.Environment.number_of_agents;
           tmp = cell(1,app.nAgents);
           app.checkCell = cell(1,app.nAgents+1);
           t = app.MapFigure.Position(2) + app.MapFigure.Position(4)-130;
           for i = 1:app.nAgents
               tmp{i} = ['Agent ',num2str(i)];
               app.checkCell{i} = uicheckbox(app.MapFigure);
               app.checkCell{i}.Position = [422 t 71 17];
               t = t-25;
               app.checkCell{i}.Text = ['Agent ',num2str(i)];   
           end
           app.checkCell{i+1} = uilabel(app.MapFigure);
           app.checkCell{i+1}.Position = [422 t 190 17];
           app.checkCell{i+1}.Text = 'Mark The Low Type Agents (W^l_l)'; 
           app.UITable.ColumnName = tmp;
           app.UITable.RowName = tmp;
           app.UITable.Position(2) = app.LoadFile.Position(2)+40;
           app.UITable.Position(4) = 17 + app.checkCell{1}.Position(2)- app.UITable.Position(2);
           app.UITable.Data = zeros(app.nAgents,app.nAgents);
%            app.SpMatrixDD.Items = createMatOptions(app.nAgents);
 
        end

        % Button pushed function: OK
        function OKButtonPushed(app, event)
            if ~all(all(app.UITable.Data == app.UITable.Data'))
                msgbox('Invalid Usage - Symmetric Matrix Only')
                return
            end
            if any(diag(app.UITable.Data))
                msgbox('Invalid Usage - No self Loops')
                return
            end
            color = ones(1,app.nAgents);
            for i = 1:app.nAgents
                if app.checkCell{i}.Value
                    color(i) = 2;
                end
            end
            app.myconfig.Model.color = color;
            f = waitbar(.33,'Processing the Environment');
            [stability, resultMessage2] = AnalyzeConfig(app.myconfig, logical(app.UITable.Data),getColorMat(app.myconfig));
            waitbar(.8,f,'Setting the Output File');
            resultMessage1 = ['The environment is '];
            if stability
                resultMessage = [resultMessage1 'STABLE'];
            else
                resultMessage = [resultMessage1 'UNSTABLE ' ,...
                    resultMessage2{:}];
            end
            saveCSR(app.myconfig,app.UITable.Data,resultMessage);
            close(f)
            closereq;
        end

        % Button pushed function: LoadFile
        function LoadFileButtonPushed(app, event)
            map = readFileGUI;
            if isempty(map)
                return
            end
            if app.nAgents ~= size(map,1)
                msgbox('Invalid Usage - enviroment size must be compatible with the original parameters');
                return
            end
            app.UITable.Data = map;
        end

        % Callback function
        function LoadMatrixButtonPushed(app, event)
            map = GuiSmaps(app.nAgents,app.SpMatrixDD.Value);
            app.UITable.Data = map;
            col = {};
            for i = 1:size(map,2)
                col{i} = num2str(i);
            end
            app.UITable.ColumnName = col;
            app.RemoveOption.Items = app.UITable.ColumnName;    
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.MapFigure = uifigure;
            app.MapFigure.Name = 'Membership Map Editing';
            app.UITable = uitable(app.MapFigure);
            app.UITable.ColumnName = {'1'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.OK = uibutton(app.MapFigure, 'push');
            app.OK.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OK.Text = 'OK';
            app.LoadMatrixFileLabel = uilabel(app.MapFigure);
            app.LoadMatrixFileLabel.Text = 'Load Matrix File';
            app.LoadFile = uibutton(app.MapFigure, 'push');
            app.LoadFile.ButtonPushedFcn = createCallbackFcn(app, @LoadFileButtonPushed, true);
            app.LoadFile.Text = 'Load';
        end
    end

    methods (Access = public)

        % Construct app
        function app = mapGui(varargin)

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.MapFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.MapFigure)
        end
    end
end